classdef Start_End_Table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UITable      matlab.ui.control.Table
        EnterStartandEndHoursForEachDatabaseLabel  matlab.ui.control.Label
        EnterStartandEndDaysForEachDatabaseLabel  matlab.ui.control.Label
        UITable_2    matlab.ui.control.Table
        EnterButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        hourlypresencetabletool % main app
        rowtitles %titles of rows for the tables
        hourlycheckboxvalue % Description
        dailycheckboxvalue
    end
    
    methods (Access = private)
        
        function [Sanctuary,Site,Deployment,Species]= extractInfoFromFilename(app, data)
            namearray = {};
        for c = 1:length(data)
            filename = data{c};
            %databank of species
            specieslist= {'Minke','dolphins'};
                
            %figure out sanctuary
            
            if contains(filename,'SB','IgnoreCase',true)
                Sanctuary= 'SB';
            elseif contains(filename,'GR','IgnoreCase',true)
                Sanctuary= 'GR';
            elseif contains(filename,'FK','IgnoreCase',true)
                Sanctuary= 'FK';
            else
                disp('Not an E Coast sanctuary')
                Sanctuary= [];
            end
            %figure out site
            filenamesplit= strsplit(filename,'_');
            sanctOptions= strfind(filenamesplit,Sanctuary);
            findSanctPos= cellfun(@isempty,sanctOptions);
            possibilities= filenamesplit(~findSanctPos);
                %now figure it out based on length and whether there are numbers and
                %letters
                Site= char(possibilities(~cellfun(@isempty,regexp(possibilities, '\d'))));
            %figure out deployment
            sitePos= regexp(filename,[Site,'_']);
            partialname= filename(sitePos:sitePos+6);
            partialname2= strsplit(partialname,'_');
            Deployment= char(partialname2(2));
            %figure out species
                whichspeciesPos= ismember(filenamesplit,specieslist);
                if ~whichspeciesPos
                   %need to also separate by .
                   splitagain= strsplit(filenamesplit{end},'.');
                   whichspeciesPos= ismember(splitagain,specieslist);
                    Species= char(splitagain(whichspeciesPos));
                else
                     Species= char(filenamesplit(whichspeciesPos));
                end
                composite_name = [Site,'_', Deployment];
                namearray = [namearray, composite_name];
                app.rowtitles = namearray;
        end
     end
 end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hourly_presence_table_tool, data, HourlyCheckBoxValue, DailyCheckBoxValue)
            %Store main app object
            app.hourlypresencetabletool = hourly_presence_table_tool;
            
            %show tables based on checkbox value and fill tables with filenames
            if  HourlyCheckBoxValue == 1
                app.hourlycheckboxvalue = 1;
                app.UITable.Visible = true;
                data = cellstr(data);
                [~,~,~,~] = extractInfoFromFilename(app, data); 
                data = app.rowtitles;
                app.UITable.RowName = data;
                app.UITable.Data = zeros(size(data,2),2);
            else
                app.UITable.Visible = false;
                app.EnterStartandEndHoursForEachDatabaseLabel.Visible = false;
                
                %move daily table up if hourly table is not being shown
                app.UITable_2.Position = [19,260,445,152];
                app.EnterStartandEndDaysForEachDatabaseLabel.Position = [19,419,340,22];
            end
            
            if  DailyCheckBoxValue == 1
                app.dailycheckboxvalue = 1;
                app.UITable_2.Visible = true;
                data = cellstr(data);
                [~,~,~,~] = extractInfoFromFilename(app, data);
                data = app.rowtitles;
                app.UITable_2.RowName = data;
                app.UITable_2.Data = zeros(size(data,2),2);
            else
                app.UITable_2.Visible = false;
                app.EnterStartandEndDaysForEachDatabaseLabel.Visible = false;
            end
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            hourData = app.UITable.Data;
            dayData = app.UITable_2.Data;
            
            
            %send start end times back to main app
            if app.hourlycheckboxvalue == 1
                getStartEndHour(app.hourlypresencetabletool, hourData);
            else
                %don't send start/end hours
            end
            
            if app.dailycheckboxvalue == 1
                getStartEndDay(app.hourlypresencetabletool, dayData)
            else
                %don't send start/end days
            end
            
            %close app
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start Hour'; 'End Hour'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [true true];
            app.UITable.Tooltip = {''};
            app.EnterStartandEndHoursForEachDatabaseLabel = uilabel(app.UIFigure);
            app.EnterStartandEndHoursForEachDatabaseLabel.Text = 'Enter Start and End Hours For Each Database';
            app.EnterStartandEndDaysForEachDatabaseLabel = uilabel(app.UIFigure);
            app.EnterStartandEndDaysForEachDatabaseLabel.Text = 'Enter Start and End Days For Each Database';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Start Day'; 'End Day'};
            app.UITable_2.RowName = {''};
            app.UITable_2.ColumnEditable = [true true];
            app.UITable_2.Tooltip = {''};
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_End_Table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end