classdef Aggregate_Data_Daily_and_Hourly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AggregateDataDailyandHourlyLabel  matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        AggregateDataOptionsButtonGroup  matlab.ui.container.ButtonGroup
        SummarizeHumpbackDataPanel      matlab.ui.container.Panel
        HourlyCheckBox                  matlab.ui.control.CheckBox
        DailyCheckBox                   matlab.ui.control.CheckBox
        RegressionAnalysisOptionsPanel  matlab.ui.container.Panel
        PostRegressionAnalysisPanel     matlab.ui.container.Panel
        HigherValueEditFieldLabel       matlab.ui.control.Label
        HigherValueEditField            matlab.ui.control.NumericEditField
        LowerValueEditFieldLabel        matlab.ui.control.Label
        LowerValueEditField             matlab.ui.control.NumericEditField
        LoadTablesButton                matlab.ui.control.Button
        PreRegressionAnalysisPanel      matlab.ui.container.Panel
        CreateregressiontablesCheckBox  matlab.ui.control.CheckBox
        UITable                         matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        PressruntoprocessfilesLabel     matlab.ui.control.Label
        StatusReadyLampLabel            matlab.ui.control.Label
        StatusReadyLamp                 matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        datafiles % source data (can be one or multiple files)
        pathname % pathname
        pathname_review % path for review_table function
        filename_review %filename for review_table function
        rowTitles % 
        regressionAnalysisApp %
        humpbackArray % 
        minkeArray %
        dolphinArray %
        MnandOdontocetesArray %
    end
    
    properties (Access = public)
        dinterest
        hinterest
        files
    end

    
    methods (Access = private)
        
        function processMinkeFiles(app, filename, pathname,depBoundsT)
            for ii = 1:length(filename)
                currentfile = filename{ii};
                msg= sprintf('Processing %s',currentfile);
                app.PressruntoprocessfilesLabel.Text = msg;
                drawnow;   
                
                dispMsg('Summarizing presence for:', currentfile);
                [newfilename, dailyResults] = writeDailyHourly2File (currentfile, pathname);
                
                dispMsg('Checking for deployment boundaries:', currentfile);
                [startValue,endValue] = getDepBounds(depBoundsT,currentfile);
                dailyResults = appendBoundaries(dailyResults,startValue,endValue,'daily');
                [lastDay,totalDays] = dayStuff(dailyResults);
                
                dispMsg('Inserting absences for:', currentfile);
                dailyResults = bothDayHour(dailyResults,totalDays,lastDay,Hr_first,Hr_last,HourRange,sumType);
                
                msg= sprintf('Creating presence table for %s',currentfile);
                app.PressruntoprocessfilesLabel.Text = msg;
                drawnow;                            
                
                dispMsg('Writing daily presence for:', currentfile);
                
                writetable(dailyResults,newfilename,'Sheet','DailyResults');
            end
        end

        function ProcessFilesDolphin(app, pathname, filenames, species,depBoundsT)
            for  ii = 1:length(filenames)
                 currentfile = filenames{ii};
                 
                 msg= sprintf('Processing %s',currentfile);
                 app.PressruntoprocessfilesLabel.Text = msg;
                 drawnow;
                 
                 %read database
                 wmd = readWMD_SQLiteTable_2inputs(pathname,currentfile,species);
                 
                 %summarize into presence tables
                 dispMsg('Summarizing hourlypres for:', currentfile);
                 [~, hourlyPresT, projectname] = summarizeDolphinDetections4(wmd, currentfile, pathname, species);
                 
                 dispMsg('Checking for deployment boundaires:', currentfile);
                 [startValue,endValue] = getDepBounds(depBoundsT,currentfile);
                 hourlyPresT = appendBoundaries(hourlyPresT,startValue,endValue,'hourly');
                 [Hr_first,Hr_last,HourRange] = hourstuff(hourlyPresT);
                 [lastDay,totalDays] = dayStuff(hourlyPresT);
                 
                 dispMsg('Inserting absences for:', currentfile);
                 hourlyPresT = bothDayHour(hourlyPresT,totalDays,lastDay,Hr_first,Hr_last,HourRange,'hourly');
                 
                 newfilename = strsplit(projectname,'_');
                 newfilename = {char(newfilename{1}), char(newfilename{2}), char(newfilename{3})};
                 newfilename = strjoin(newfilename, '_');
                 newfilename = newfilename(~isspace(newfilename));
                 
                 msg= sprintf('Writing hourly presence for %s',currentfile);
                 app.PressruntoprocessfilesLabel.Text = msg;
                 drawnow;
                 
                 dispMsg('Writing hourly presence for:', currentfile);
                 presTfilename = [char(pathname),char(newfilename),'_', char(species),'.xlsx'];
                 writetable(hourlyPresT,presTfilename,'Sheet','HourlyPres');
                 regressionTable(app,pathname,currentfile,newfilename,species);
            end
        end
        
        function ProcessFilesHumpback(app, pathname, filenames, species,depBoundsT)
            for  ii = 1:length(filenames)
                 currentfile = filenames{ii};
                 
                 msg= sprintf('Processing %s',currentfile);
                 app.PressruntoprocessfilesLabel.Text = msg;
                 drawnow;
                 
                 %read database
                 wmd = readWMD_SQLiteTable_2inputs(pathname,currentfile,species);
                 
                 dispMsg('Summarizing presence for:', currentfile);
                 [dailyPres, hourlyPresT, projectname] = summarizeDolphinDetections4(wmd, currentfile, pathname, species);
                 [startValue,endValue] = getDepBounds(depBoundsT,currentfile);
                
                 newfilename = strsplit(projectname,'_');
                 newfilename = {char(newfilename{1}), char(newfilename{2}), char(newfilename{3})};  
                 newfilename = strjoin(newfilename, '_');
                 newfilename = newfilename(~isspace(newfilename));
                
                 %write tables depending on checkbox values
                 if app.HourlyCheckBox.Value == 1 
                    msg= sprintf('Writing hourly presence for %s',currentfile);
                    app.PressruntoprocessfilesLabel.Text = msg;
                    drawnow;                    
                    
                    dispMsg('Checking for deployment boundaries:', currentfile);
                    hourlyPresT = appendBoundaries(hourlyPresT,startValue,endValue,'hourly');
                    [Hr_first,Hr_last,HourRange] = hourstuff(hourlyPresT);
                    [lastDay,totalDays] = dayStuff(hourlyPresT);
                    
                    dispMsg('Inserting absences for:', currentfile);
                    hourlyPresT = bothDayHour(hourlyPresT,totalDays,lastDay,Hr_first,Hr_last,HourRange,'hourly');

                    dispMsg('Writing hourly presence for:', currentfile);
                    
                    presTfilename = [char(pathname),char(newfilename),'_',char(species),'.xlsx'];
                    writetable(hourlyPresT,presTfilename,'Sheet','HourlyPres');
                    regressionTable(app,pathname,currentfile,newfilename,species);
                    
                 end
                 if app.DailyCheckBox.Value == 1
                    msg= sprintf('Writing daily presence for %s',currentfile);
                    app.PressruntoprocessfilesLabel.Text = msg;
                    drawnow;                           
                    
                    dispMsg('Checking for deployment boundaries:', currentfile);
                    dailyPres = appendBoundaries(dailyPres,startValue,endValue,'daily');
                    [lastDay,totalDays] = dayStuff(dailyPres);
                    
                    dispMsg('Inserting absences for:', currentfile);
                    dailyPres = bothDayHour(dailyPres,totalDays,lastDay,'','','','daily');

                    dispMsg('Writing daily presence for:', currentfile);
                    PresTfilename = [char(pathname),char(newfilename),'_',char(species),'.xlsx'];
                    writetable(dailyPres,PresTfilename,'Sheet','DailyPres'); 
                 end
            end
        end
        
        function regressionTable(app,pathname,currentfile,newfilename,species)
            if ~isempty(app.files)
                UIfiles = cellstr(app.files);
                lia = ismember(UIfiles,currentfile);
                currentfile = UIfiles(lia);
                if ~isempty(currentfile)
                    msg= sprintf('Creating regression table for %s',string(currentfile));
                    app.PressruntoprocessfilesLabel.Text = msg;
                    drawnow;                       
                    
                    dispMsg('Writing regression table for:',currentfile);
                    
                    createRegressionTable(pathname,currentfile,newfilename,species,app.dinterest,app.hinterest)
                else
                    %don't create presence table
                end
            else
            end
        end
    end
    
    methods (Access = public)
        function getDatafromApp(app,dinterest,hinterest,files)
            app.dinterest = dinterest;
            app.hinterest = hinterest;
            app.files = files;
            dispMsg('Days chosen for regression:',str2double(app.dinterest));
            dispMsg('Hours Chosen for regression:',str2double(app.hinterest));
            dispMsg('Files chosen for regression:',app.files);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.ColumnFormat = {'char' 'char'};
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        %make the run light red and change text to busy
        app.StatusReadyLamp.Color = 'red';
        app.StatusReadyLampLabel.Text = 'Status: Busy';
        drawnow;
        
        dispMsg('Started processing files:',app.datafiles);
        %app.RunButton.Enable = 'off';
        app.CreateregressiontablesCheckBox.Enable = 'off';
        
        if app.CreateregressiontablesCheckBox.Value == 1
            dispMsg('Called regression app','');
            if app.HourlyCheckBox.Value == 0 && app.DailyCheckBox.Value == 0
                file_array = cellstr(app.dolphinArray); 
            else
                file_array = [cellstr(app.MnandOdontocetesArray),cellstr(app.dolphinArray)];
            end
            app.regressionAnalysisApp = regressionAnalysisDataSelector(app, file_array);
        
            %wait for pop-up window to close when user presses enter
            waitfor(app.regressionAnalysisApp);
            dispMsg('Closed regression app','');
        end
        if ~isempty(app.datafiles)
            %put UITable data into regular table variable
            depBounds = app.UITable.Data;
            startValues = depBounds(:,1);
            endValues = depBounds(:,2);
            depNames = cellstr(app.datafiles);
            depBoundsT = table(transpose(depNames),startValues,endValues);
            
            ProcessFilesDolphin(app, app.pathname, cellstr(app.dolphinArray), 'dolphins',depBoundsT); 
            
            ProcessFilesHumpback(app, app.pathname, cellstr(app.humpbackArray),'humpback',depBoundsT);
                              
            ProcessFilesHumpback(app, app.pathname, cellstr(app.MnandOdontocetesArray),'humpback',depBoundsT);
                             
            processMinkeFiles(app, cellstr(app.minkeArray), app.pathname,depBoundsT)
        else
            %do nothing
        end

        %process presence tables with post-regression results
        app.filename_review = cellstr(app.filename_review);
        length(app.filename_review)
        if isequal(app.filename_review, 0)
               %do nothing if no files were loaded
        else
          for  k = 1:length(app.filename_review)
                %read array of files
                currentfile_review = app.filename_review{k};
                 
                %change label to the current file that's being processed
                msg= sprintf('Applying regression results to %s',currentfile_review);
                app.PressruntoprocessfilesLabel.Text = msg;
                drawnow;
                
                dispMsg('Applying regression results to:',currentfile_review);
                
                %set the range of numbers to look at
                lowest_num = app.LowerValueEditField.Value;
                highest_num = app.HigherValueEditField.Value;
                
                %call the postRegression function
                postRegression(app.pathname_review,currentfile_review, lowest_num, highest_num)  
           end 
       end
            
         %make the run light green and change text to status
         app.StatusReadyLamp.Color = 'green';
         app.StatusReadyLampLabel.Text = 'Status: Ready';
         
         %return label on run button back to default value
         app.PressruntoprocessfilesLabel.Text = 'Press run to process a database';
         drawnow;
         app.CreateregressiontablesCheckBox.Enable = 'on';
         app.RunButton.Enable = 'on';
         dispMsg('Processing completed','');
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %change status lamp to red and say busy
            app.StatusReadyLamp.Color = 'red';
            app.StatusReadyLampLabel.Text = 'Status: Busy';
            drawnow;
            
            [app.datafiles,  app.pathname]= uigetfile({'*.sqlite3;*.txt'},'Select Data To Process',"MultiSelect","on");
            
            %refocus app as primary window
            figure(app.UIFigure)
            
            %if user selects no files
            if isequal(app.datafiles, 0)
               dispMsg('No data loaded','');
            else
               dispMsg('Loaded data:',app.datafiles);
               
               %sort files
               file_array = cellstr(app.datafiles);
               [app.humpbackArray,app.minkeArray,app.dolphinArray,app.MnandOdontocetesArray,~] = organizeFilesSpecies(file_array);
               
               %disable humpback summarize options if there are no humpback
               %files loaded
               if isempty(app.MnandOdontocetesArray)
                   app.HourlyCheckBox.Enable = 'off';
                   app.HourlyCheckBox.Value = 0;
                   app.DailyCheckBox.Enable = 'off';
                   app.DailyCheckBox.Value = 0;
               else 
                   app.HourlyCheckBox.Enable = 'on';
                   app.HourlyCheckBox.Value = 0;
                   app.DailyCheckBox.Enable = 'on';
                   app.DailyCheckBox.Value = 0;
               end
                
               %load files into deployment info table
                rowNames = getRowNames(file_array);
                app.rowTitles = rowNames;
                data = app.rowTitles;
                app.UITable.RowName = data;
                
                %fill table with data
                num = size(data,2);
                tableData = {};
                fillcell = char('MM/dd/yyyy HH:mm:ss');
                for ii = 1:num
                    tableData{ii,1} = fillcell;
                    tableData{ii,2} = fillcell;
                end
                app.UITable.Data = tableData;
            end
            
            %set status lamp to green and say status  
            app.StatusReadyLamp.Color = 'green';
            app.StatusReadyLampLabel.Text = 'Status: Ready';
            drawnow;
        end

        % Button pushed function: LoadTablesButton
        function LoadTablesButtonPushed(app, event)
            %make status lamp red and say busy
            app.StatusReadyLamp.Color = 'red';
            app.StatusReadyLampLabel.Text = 'Status: Busy';
            drawnow;
            
            %open browser to retrieve files
            [app.filename_review, app.pathname_review]= uigetfile('*.xlsx','Select tables','MultiSelect','on');         
            
            %refocus app as primary window
            figure(app.UIFigure)
 
            if isequal(app.filename_review, 0)
               dispMsg('No data selected to apply regression results','');
            else     
               %convert to cell array so for loop can read it
               dispMsg('Loaded files to apply regression results:',app.filename_review);
            end
            
            %change status lamp to green and say status
            app.StatusReadyLamp.Color = 'green';
            app.StatusReadyLampLabel.Text = 'Status: Ready';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AggregateDataDailyandHourlyLabel = uilabel(app.UIFigure);
            app.AggregateDataDailyandHourlyLabel.Text = 'Aggregate Data Daily and Hourly';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.AggregateDataOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.AggregateDataOptionsButtonGroup.Title = 'Aggregate Data Options';
            app.SummarizeHumpbackDataPanel = uipanel(app.AggregateDataOptionsButtonGroup);
            app.SummarizeHumpbackDataPanel.Title = 'Summarize Humpback Data';
            app.HourlyCheckBox = uicheckbox(app.SummarizeHumpbackDataPanel);
            app.HourlyCheckBox.Enable = 'off';
            app.HourlyCheckBox.Text = 'Hourly';
            app.DailyCheckBox = uicheckbox(app.SummarizeHumpbackDataPanel);
            app.DailyCheckBox.Enable = 'off';
            app.DailyCheckBox.Text = 'Daily';
            app.RegressionAnalysisOptionsPanel = uipanel(app.UIFigure);
            app.RegressionAnalysisOptionsPanel.Title = 'Regression Analysis Options';
            app.PostRegressionAnalysisPanel = uipanel(app.RegressionAnalysisOptionsPanel);
            app.PostRegressionAnalysisPanel.Title = 'Post-Regression Analysis';
            app.HigherValueEditFieldLabel = uilabel(app.PostRegressionAnalysisPanel);
            app.HigherValueEditFieldLabel.Text = 'Higher Value';
            app.HigherValueEditField = uieditfield(app.PostRegressionAnalysisPanel, 'numeric');
            app.HigherValueEditField.ValueDisplayFormat = '%.0f';
            app.HigherValueEditField.Tooltip = {'anything above this value marked as positive detection'};
            app.LowerValueEditFieldLabel = uilabel(app.PostRegressionAnalysisPanel);
            app.LowerValueEditFieldLabel.Text = 'Lower Value';
            app.LowerValueEditField = uieditfield(app.PostRegressionAnalysisPanel, 'numeric');
            app.LowerValueEditField.ValueDisplayFormat = '%.0f';
            app.LowerValueEditField.Tooltip = {'anything below this value marked as negative detection '};
            app.LoadTablesButton = uibutton(app.PostRegressionAnalysisPanel, 'push');
            app.LoadTablesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTablesButtonPushed, true);
            app.LoadTablesButton.Text = 'Load Tables';
            app.PreRegressionAnalysisPanel = uipanel(app.RegressionAnalysisOptionsPanel);
            app.PreRegressionAnalysisPanel.Title = 'Pre-Regression Analysis';
            app.CreateregressiontablesCheckBox = uicheckbox(app.PreRegressionAnalysisPanel);
            app.CreateregressiontablesCheckBox.Text = 'Create regression tables';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start Date (MM/dd/yyyy HH:mm:ss)'; 'End Date (MM/dd/yyyy HH:mm:ss)'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = true;
            app.UITable.Tooltip = {'Enter deployment info '};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PressruntoprocessfilesLabel = uilabel(app.UIFigure);
            app.PressruntoprocessfilesLabel.Text = 'Press run to process files';
            app.StatusReadyLampLabel = uilabel(app.UIFigure);
            app.StatusReadyLampLabel.Text = 'Status: Ready';
            app.StatusReadyLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aggregate_Data_Daily_and_Hourly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end