classdef Start_End_Table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UITable      matlab.ui.control.Table
        EnterStartEndDatestobeRemovedForEachDatabaseLabel  matlab.ui.control.Label
        UITable_2    matlab.ui.control.Table
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        EnterButton  matlab.ui.control.Button
        EnterthefirstlastpartialhoursofthedeploymentLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        hourlypresencetabletool % main app
        rowtitles %titles of rows for the tables
        hourlycheckboxvalue % Description
        dailycheckboxvalue
    end
    
    methods (Access = private)
        
        
        function createRowTitles(app, data)
            namearray = {};
            for c = 1:length(data)
                filename = data{c};
            
                [~,Site,Deployment,~] = extractInfoFromFilename(filename);
            
                composite_name = [Site,'_', Deployment];
                namearray = [namearray, composite_name];
            end
            app.rowtitles = namearray;
        end
 end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hourly_presence_table_tool, data, HourlyCheckBoxValue, DailyCheckBoxValue)
            %Store main app object
            app.hourlypresencetabletool = hourly_presence_table_tool;
            
            %show tables based on checkbox value and fill tables with filenames
            if  HourlyCheckBoxValue == 1
                app.hourlycheckboxvalue = 1;
                app.UITable.Visible = true;
                data = cellstr(data);
                createRowTitles(app, data); 
                data = app.rowtitles;
                app.UITable.RowName = data;
                app.UITable.Data = zeros(size(data,2),2);
            else
                app.UITable.Visible = false;
                app.EnterthefirstlastpartialhoursofthedeploymentLabel.Visible = false;
                app.Label.Visible = false;
                app.Label_2.Visible = false; 
                
                %move daily table up if hourly table is not being shown
                app.UITable_2.Position = [22,326,546,152];
                app.EnterStartEndDatestobeRemovedForEachDatabaseLabel.Position = [116,485,359,22];
            end
            
            if  DailyCheckBoxValue == 1
                app.dailycheckboxvalue = 1;
                app.UITable_2.Visible = true;
                data = cellstr(data);
                if HourlyCheckBoxValue == 1
                   data = app.rowtitles;
                   app.UITable_2.RowName = data;
                   app.UITable_2.Data = zeros(size(data,2),6);
                else 
                   createRowTitles(app, data);
                   data = app.rowtitles;
                   app.UITable_2.RowName = data;
                   app.UITable_2.Data = zeros(size(data,2),6); 
                end

            else
                app.UITable_2.Visible = false;
                app.EnterStartEndDatestobeRemovedForEachDatabaseLabel.Visible = false;
            end
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            hourData = app.UITable.Data;
            dayData = app.UITable_2.Data;
            
            
            %send start end times back to main app
            if app.hourlycheckboxvalue == 1
                getStartEndHour(app.hourlypresencetabletool, hourData);
            else
                %don't send start/end hours
            end
            
            if app.dailycheckboxvalue == 1
                getStartEndDay(app.hourlypresencetabletool, dayData)
            else
                %don't send start/end days
            end
            
            %close app
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Start Hour'; 'End Hour'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [true true];
            app.UITable.Tooltip = {''};
            app.EnterStartEndDatestobeRemovedForEachDatabaseLabel = uilabel(app.UIFigure);
            app.EnterStartEndDatestobeRemovedForEachDatabaseLabel.Text = 'Enter Start/End Dates to be Removed For Each Database';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Start Month'; 'Start Day'; 'Start Year (yyyy)'; 'End Month'; 'End Day'; 'End Year (yyyy)'};
            app.UITable_2.RowName = {''};
            app.UITable_2.ColumnEditable = true;
            app.UITable_2.Tooltip = {''};
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.EnterthefirstlastpartialhoursofthedeploymentLabel = uilabel(app.UIFigure);
            app.EnterthefirstlastpartialhoursofthedeploymentLabel.Text = 'Enter the first/last partial hours of the deployment';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Start Hour = the hour the unit was deployed, a partial hour (not to be analyzed and'; 'will be removed)'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'End Hour = hour the unit was recovered, a partial hour (not to be'; 'analyzed and will be removed).'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Start_End_Table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end