classdef NCEI_format_tool_AID < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReadyLampLabel             matlab.ui.control.Label
        ReadyLamp                  matlab.ui.control.Lamp
        RunButton                  matlab.ui.control.Button
        CurrentlyProcessingLabel   matlab.ui.control.Label
        NCEIFormatToolLabel        matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        DetectorTypeButtonGroup    matlab.ui.container.ButtonGroup
        DolphinButton              matlab.ui.control.RadioButton
        MinkeButton                matlab.ui.control.RadioButton
        CodButton                  matlab.ui.control.RadioButton
        VesselButton               matlab.ui.control.RadioButton
        GrouperButton              matlab.ui.control.RadioButton
        PingerButton               matlab.ui.control.RadioButton
        LFDCSButton                matlab.ui.control.RadioButton
        BrowseButton               matlab.ui.control.Button
        CurrentlySelectedTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        filename
        pathname
        file_array 
        suprafolder % suprafolder for file saving
    end
    
    methods (Access = private)
        
        function minke_detector(app, ~, ~)
        
            for i = 1:length(app.file_array)
                currentfile = app.file_array{i};
                
                msg = sprintf('Processing %s',currentfile);
                app.CurrentlyProcessingLabel.Text = msg;
                drawnow
                
                %some variable names may be different between users. Coerce all variable
                %names in table to be the same
                dataInput = readtable([app.pathname, currentfile],'Sheet', 'DailyResults');
                dataInput.Properties.VariableNames= {'Date','SelNo','Present'};
                
                %go through the data and remove any blank rows
                %dataInput = rmmissing(dataInput,'DataVariables','Present');                
                
                %coerce all 2/3 to 0s
                unsure= find(dataInput.Present==2);
                dataInput.Present(unsure)= 0;
                    %if any number besides 0-1, sprintf them for the user to go back and
                    %fix. Don't continue the conversion
                    notAccepted= find(dataInput.Present>2);
                    if ~isempty(notAccepted)
                       disp('You have entered presence values that are not accepted (0-2).')
                       errMsg2= fprintf('Please go back to the Excel file and check rows %d \n',...
                           notAccepted+1);
                    else
                        Minke_dates = dataInput.Date;
                        Convert_dates = datetime(Minke_dates, 'InputFormat', 'MM/dd/yyyy');
                        Convert_dates = datestr(Convert_dates,'yyyy-mm-dd');
                        ISOStartDate= string(Convert_dates);
                        Presence = dataInput.Present;
                        dataOut= table(ISOStartDate,Presence);
                        dataOut = noBlanks(app, dataOut);
                        
                        [Sanctuary,Site,Deployment,Species] = extractInfoFromFilename(app, currentfile);
                        new_filename = ['SanctSound_',char(Site),'_',char(Deployment),'_', char(Species),'_1d','.csv'];
                        site_deployment = [char(Site),'_',char(Deployment)];
                        exportFiles(app, dataOut, new_filename, Sanctuary, site_deployment);
                    end           
            end
        end
        
        function vessel_detector(app, ~, ~)
             for i = 1:length(app.file_array)
                currentfile = app.file_array{i};
                
                msg = sprintf('Processing %s',currentfile);
                app.CurrentlyProcessingLabel.Text = msg;
                drawnow
                
                Vessel_table = readtable([app.pathname, currentfile]);
                Find_Ship = Vessel_table(strcmp(Vessel_table.Labels, 'ship'), :);
                dataOut = removevars(Find_Ship,{'StartTime', 'EndTime'});
                
                [Sanctuary,Site,Deployment,Species] = extractInfoFromFilename(app, currentfile);
                new_filename = ['SanctSound_', char(Site),'_',char(Deployment), '_', char(Species), '.csv'];
                site_deployment = [char(Site),'_',char(Deployment)];
                exportFiles(app, dataOut, new_filename, Sanctuary, site_deployment);
             end
        end
        
        function [Sanctuary,Site,Deployment,Species]= extractInfoFromFilename(app, currentfile)
            %databank of species
            specieslist= {'Minke','dolphins','RW','fin','sei','blue','humpback','cod','Ship'};
                
            %figure out sanctuary
            
            if contains(currentfile,'SB','IgnoreCase',true)
                Sanctuary= 'SB';
            elseif contains(currentfile,'GR','IgnoreCase',true)
                Sanctuary= 'GR';
            elseif contains(currentfile,'FK','IgnoreCase',true)
                Sanctuary= 'FK';
            else
                disp('Not an E Coast sanctuary')
                Sanctuary= [];
            end
            %figure out site
            filenamesplit= strsplit(currentfile,'_');
            sanctOptions= strfind(filenamesplit,Sanctuary);
            findSanctPos= cellfun(@isempty,sanctOptions);
            possibilities= filenamesplit(~findSanctPos);
                %now figure it out based on length and whether there are numbers and
                %letters
                Site= char(possibilities(~cellfun(@isempty,regexp(possibilities, '\d'))));
            %figure out deployment
            sitePos= regexp(currentfile,[Site,'_']);
            partialname= currentfile(sitePos:sitePos+6);
            partialname2= strsplit(partialname,'_');
            Deployment= char(partialname2(2));
            %figure out species
                whichspeciesPos= ismember(filenamesplit,specieslist);
                if ~whichspeciesPos
                   %need to also separate by .
                   splitagain= strsplit(filenamesplit{end},'.');
                   whichspeciesPos= ismember(splitagain,specieslist);
                    Species= char(splitagain(whichspeciesPos));
                else
                     Species= char(filenamesplit(whichspeciesPos));
                end
            
        end
        
        function dataOut= dolphinDetector4NCEI(app, ~, ~)
             for i = 1:length(app.file_array)
                currentfile = app.file_array{i};
                
                msg = sprintf('Processing %s',currentfile);
                app.CurrentlyProcessingLabel.Text = msg;
                drawnow
                
                dataInput = readtable([app.pathname, currentfile],'Sheet', 'HourlyPres');

                %some variable names may be different between users. Coerce all variable
                %names in table to be the same
                dataInput.Properties.VariableNames= {'Day','Hour','nDet','MedLowFreq',...
                    'MedHighFreq','Presence','Band1','Band2','Comments'};
                
                %go through the data and remove any blank rows
                %dataInput = rmmissing(dataInput,'DataVariables','Presence');
                
                %coerce all 2/3 to 0s
                unsure= find(dataInput.Presence==2);
                clicks= find(dataInput.Presence==3);
                dataInput.Presence(unsure)= 0;
                dataInput.Presence(clicks)= 0;
                    %if any number besides 0-3, sprintf them for the user to go back and
                    %fix. Don't continue the conversion
                    notAccepted= find(dataInput.Presence>3);
                    if ~isempty(notAccepted)
                       disp('You have entered presence values that are not accepted (0-3).')
                       errMsg2= fprintf('Please go back to the Excel file and check rows %d \n',...
                           notAccepted);
                    else
                        %if all ok, go ahead and apply the ISO format
                            %combine Day and Hour column
                            nRows= length(dataInput.Hour);
                            temp= cell(nRows,1);
                            for i= 1:nRows
                                %if hours 0-9 need to convert them to 2 digits
                                if dataInput.Hour(i)<10 
                                temp{i}= datestr(datetime([num2str(dataInput.Hour(i)),':00:00'],...
                                    'InputFormat','H:mm:ss'));
                                else
                                    temp{i}= datestr(datetime([num2str(dataInput.Hour(i)),':00:00'],...
                                    'InputFormat','HH:mm:ss'));
                                end
                            end
                           
                            dataInput.Time= temp;
                            dataInput.Day.Format = 'MM/dd/yyyy HH:mm:ss';
                            dataInput.Time= datetime(dataInput.Time, 'Format', 'MM/dd/yyyy HH:mm:ss');
                            findMidnight= isnat(dataInput.Time);
                            dataInput.Time(findMidnight)= datetime('00:00:00','Format', 'MM/dd/yyyy HH:mm:ss');
                            dataInput.Datetime = dataInput.Day + timeofday(dataInput.Time);
                            
                            %need Matlab serial dates to go into ISO conversion function
                            serialDatetime= datenum(dataInput.Datetime);
                            ISOStartTime= dbSerialDateToISO8601(app, serialDatetime);
                            Presence= dataInput.Presence;
                            dataOut= table(ISOStartTime,Presence);
                            dataOut = noBlanks(app, dataOut);
                            
                            [Sanctuary,Site,Deployment,Species] = extractInfoFromFilename(app, currentfile);
                            new_filename = ['SanctSound_', char(Site),'_',char(Deployment), '_', char(Species),'_1h', '.csv'];
                            site_deployment = [char(Site),'_',char(Deployment)];

                            exportFiles(app, dataOut, new_filename, Sanctuary, site_deployment);
                    end
             end
        end
        
        
        function ISOStartTime = dbSerialDateToISO8601(app, serialDatetime)
            % Convert a set of of Matlab serial dates to ISO8601 format
            % It is assumed that the dates are in UTC.
            
            if numel(serialDatetime) > 1
                ISOStartTime = cell(size(serialDatetime));
                for t = 1:numel(serialDatetime)
                    ISOStartTime{t} = SerialToISO(serialDatetime(t));
                end
            else
                ISOStartTime = SerialToISO(serialDatetime);
            end
            
            function ISOStartTime = SerialToISO(serialDatetime)
            ISOStartTime = datestr(serialDatetime, 'YYYY-mm-ddTHH:MM:SSZ');
            end
        end
        
        function exportFiles(app, dataOut, new_filename, Sanctuary, site_deployment)
            cd(app.suprafolder)
            if  isfolder(Sanctuary) == 1
                currentfolder = pwd;
                newfoldername = [char(currentfolder),'\',Sanctuary];
                cd(newfoldername)
                if isfolder(site_deployment) == 1
                   sitefoldername = [char(newfoldername),'\',site_deployment];
                   cd(sitefoldername)
                   save_path = pwd;
                   full_pathfilename = fullfile(save_path, new_filename);
                   
                   writetable(dataOut,full_pathfilename);
                elseif isfolder (site_deployment) == 0
                   mkdir(site_deployment);
                   sitefoldername = [char(newfoldername),'\',site_deployment];
                   cd(sitefoldername)
                   save_path = pwd;
                   full_pathfilename = fullfile(save_path, new_filename);
                   
                   writetable(dataOut,full_pathfilename);
                end
            elseif isfolder(Sanctuary) == 0 
                mkdir(Sanctuary)
                currentfolder = pwd;
                newfoldername = [char(currentfolder),'\',Sanctuary];
                cd(newfoldername)
                
                if isfolder(site_deployment) == 1
                   sitefoldername = [char(newfoldername),'\',site_deployment];
                   cd(sitefoldername)
                   save_path = pwd;
                   full_pathfilename = fullfile(save_path, new_filename);
                   
                   writetable(dataOut,full_pathfilename);
                elseif isfolder (site_deployment) == 0
                   mkdir(site_deployment);
                   sitefoldername = [char(newfoldername),'\',site_deployment];
                   cd(sitefoldername)
                   save_path = pwd;
                   full_pathfilename = fullfile(save_path, new_filename);
                   
                   writetable(dataOut,full_pathfilename);
                end
            
            end
        end
        
        function dataOut= codDetector4NCEI(app, ~, ~)
            for i = 1:length(app.file_array)
                currentfile = app.file_array{i};
                
                msg = sprintf('Processing %s',currentfile);
                app.CurrentlyProcessingLabel.Text = msg;
                drawnow
                
                dataInput = readtable([app.pathname, currentfile], 'ReadVariableNames', true);
                
                %Get the column names correct
                dataInput.Properties.VariableNames= {'Sel','View','NotImportant','Channel',...
                    'BeginSec','EndSec','LowFreq','HighFreq','BeginDate','StartTime','EndTime',...
                    'SNR','Confidence','Class','Presence'};
                
                %Convert Y/Ns to 1/0
                nRow= length(dataInput.Presence);
                yeses= strcmp(dataInput.Presence,'Y');
                Presence= NaN(nRow,1);
                Presence(yeses)= 1;
                nos= strcmp(dataInput.Presence,'N');
                Presence(nos)= 0;
                %Check for any other letter in Cod (Y/N)? Column,
                otherLetters= isnan(Presence);
                
                if otherLetters
                    %there are other letters than Y and N
                    %stop the operation and spit warning message
                    disp('You have entered other letters besides Y and N for cod presence.');
                    disp('Please check the following data entries:');
                    toCheck= table(dataInput.Sel(otherLetters),...
                        dataInput.BeginDate(otherLetters),dataInput.StartTime(otherLetters),...
                        dataInput.Presence(otherLetters));
                    toCheck.Properties.VariableNames= {'Sel','Date','StartTime','Labeled'};
                    disp(toCheck);
                else %can resume
                    %Combine the Begin Date with the Begin Clock Time and End Clock Time
                    %columns
                    dataInput.BeginDate= datetime(dataInput.BeginDate,'InputFormat','yyyy/M/dd');
                    dataInput.BeginDate.Format = 'MM/dd/yyyy HH:mm:ss';
                    StartDateTime= dataInput.BeginDate+dataInput.StartTime;
                    EndDateTime= dataInput.BeginDate+dataInput.EndTime;
                    
                    %then convert to ISO format
                    serialDatetimeStart= datenum(StartDateTime);
                    ISOStartTime= dbSerialDateToISO8601(serialDatetimeStart);
                    serialDatetimeEnd= datenum(EndDateTime);
                    ISOEndTime= dbSerialDateToISO8601(serialDatetimeEnd);
                    
                    dataOut= table(ISOStartTime,ISOEndTime,Presence);
                    dataOut = noBlanks(app, dataOut);
                    
                    [~,Site,Deployment,Species] = extractInfoFromFilename(app, currentfile);
                    new_filename = ['SanctSound_', char(Site),'_',char(Deployment), '_', char(Species), '.csv'];
                    site_deployment = [char(Site),'_',char(Deployment)];
                    exportFiles(app, dataOut, new_filename, Sanctuary, site_deployment);
                end
            end
        end
        
        function trncData= removeLFDCSHeader(app, data, currentfile)
            
            detectorType = finddetectorType(app, currentfile);
            switch detectorType
                case {'sei', 'blue'}
                    %sei and blue are the same (start at row 18)
                    trncData= data(17:end,:);
                case 'fin'
                    %fin is at 16
                    trncData= data(15:end,:);
                case 'RW'
                    %narw is at 20
                    trncData= data(19:end,:);
                case 'humpback'
                    %hump is at 24
                    trncData= data(23:end,:);
                otherwise
                    disp('Invalid detector type selected');
            end
            
            %get the column headers from the table
            colnames= table2cell(trncData(1,:));
            
            %check to see if the column names are in Matlab format
            Mformat= cellfun(@isvarname,colnames);
            
                %if not, change them to be
                %probably because there's spaces- remove them
                   newNames= strrep(colnames(Mformat==0),' ','_');
                   colnames(Mformat==0)= newNames;
                %remove ?
                findquest= contains(colnames,'?');
                colnames(findquest)= strrep(colnames(findquest),'?','');
                %remove /
                findslash= contains(colnames,'/');
                colnames(findslash)= strrep(colnames(findslash),'/','_');
                
            %once done, remove the column names from the table and we're done
            trncData(1,:)= [];
            ncols= length(colnames);
            if ncols==20
                trncData(:,20)= [];
                colnames(20)= [];
            end
            trncData.Properties.VariableNames= colnames;
        end
        
        function dataOut= LFDCS4NCEI(app, ~, ~)
            for i = 1:length(app.file_array)
                currentfile = app.file_array{i};
                
                msg = sprintf('Processing %s',currentfile);
                app.CurrentlyProcessingLabel.Text = msg;
                drawnow
                
                data = readtable([app.pathname, currentfile], "ReadVariableNames",true);
                trncData = removeLFDCSHeader(app, data, currentfile);
                
                nrows= length(trncData.Manual_Review);
                
                %Use columns start date and Manual_Review
                %Insert the Sanctuarysite_deployment column (SB_01_01)
                [Sanctuary,Site,Deployment,Species]= extractInfoFromFilename(app, currentfile);
                ssd= cellstr([Site(1:2),'_',Site(3:4),'_',Deployment]);
                Sanctuarysite_deployment= repmat(ssd,nrows,1);
                %Coerce all 2s to 0s
                trncData.Manual_Review= str2double(trncData.Manual_Review);
                maybes= find(trncData.Manual_Review== 2);
                trncData.Manual_Review(maybes)= 0;
                if strcmp(Species,'fin')
                    %Has an if statement for finwhale to do the extra step Find the days 
                %without presence (blanks) and autofill as 0
                trncData.Manual_Review(isnan(trncData.Manual_Review))= 0;
                end
                Presence= trncData.Manual_Review;
                %Convert start date to ISO
                %yyyy-mm-dd 
                ISO_Date= datetime(trncData.start_date,'InputFormat','M/d/yy','Format',...
                    'yyyy-MM-dd');
                
                dataOut= table(ISO_Date,Sanctuarysite_deployment,Presence);
                dataOut = noBlanks(app, dataOut);
                %dataOut.Properties.VariableNames{1}= 'ISO Date'; %can't have a space in
                %the column name...now what?
                %the input filename for species is not what NCEI wants-
                %needs to be fully written out
                switch Species
                    case 'RW'
                        Species= 'northatlanticrightwhale';
                    case 'fin'
                        Species= 'finwhale';
                    case 'sei'
                        Species= 'seiwhale';
                    case 'blue'
                        Species= 'bluewhale';
                    otherwise %humpback
                        Species= 'humpbackwhale';
                end
                new_filename = ['SanctSound_', char(Site),'_',char(Deployment), '_','LFDCS_', char(Species),'_1d', '.csv'];
                site_deployment = [char(Site),'_',char(Deployment)];
                exportFiles(app, dataOut, new_filename, Sanctuary, site_deployment);
            end 
        end
        
        function detectorType = finddetectorType(app, currentfile)
            [~,~,~,Species] = extractInfoFromFilename(app, currentfile);
           
            switch Species
                case 'sei'
                    detectorType = 'sei';
            
                case 'RW'
                    detectorType = 'RW';
            
                case 'fin'
                    detectorType = 'fin';
            
                case 'blue'
                    detectorType = 'blue';
                        
                case'humpback'
                    detectorType = 'humpback';
                otherwise
                    
            end
        end
        
        function data = noBlanks(app, data)
            brows= isnan(data.Presence);
            data.Presence(brows)= 0; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.ReadyLamp.Color = 'red';
            app.ReadyLampLabel.Text = 'busy';
            drawnow

            if app.DolphinButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.xlsx', 'MultiSelect',"on");
               
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            if app.MinkeButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.xlsx', 'MultiSelect',"on");
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            if app.CodButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.txt', 'MultiSelect',"on");
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            if app.VesselButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.csv', 'MultiSelect',"on");
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            if app.GrouperButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.xlsx', 'MultiSelect',"on");
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            if app.PingerButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.xlsx', 'MultiSelect',"on");
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            if app.LFDCSButton.Value == true
               [app.filename, app.pathname] = uigetfile('*.csv', 'MultiSelect',"on");
                if isequal(app.filename, 0)
                   app.CurrentlySelectedTextArea.Value = 'Please select files...';
                else
                   app.file_array = cellstr(app.filename); 
                   app.CurrentlySelectedTextArea.Value = app.file_array;
                end
            end
            
            app.ReadyLamp.Color = 'green';
            app.ReadyLampLabel.Text = 'Ready';
            drawnow
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.ReadyLamp.Color = 'red';
            app.ReadyLampLabel.Text = 'busy';
            drawnow
            
            app.suprafolder = uigetdir('This PC','Select Save Location' );
            
            app.CurrentlyProcessingLabel.Visible = 'On';
            if app.MinkeButton.Value == true
                minke_detector(app, app.pathname, app.file_array);
            end
            
            if app.VesselButton.Value == true
               vessel_detector(app, app.pathname, app.file_array);
            end
            
            if app.DolphinButton.Value == true
               dolphinDetector4NCEI(app, app.pathname, app.file_array);
            end
            
            if app.CodButton.Value == true
               codDetector4NCEI(app, app.pathname, app.file_array);
            end
            
            if app.GrouperButton.Value == true 
            end
            
            if app.PingerButton.Value == true
            end
            
            if app.LFDCSButton.Value == true
               LFDCS4NCEI(app, app.pathname, app.file_array); 
            end
            
            msg = 'Processing finished';
            app.CurrentlyProcessingLabel.Text = msg;
            app.ReadyLamp.Color = 'green';
            app.ReadyLampLabel.Text = 'Ready';
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CurrentlyProcessingLabel = uilabel(app.UIFigure);
            app.CurrentlyProcessingLabel.Visible = 'off';
            app.CurrentlyProcessingLabel.Text = 'Currently Processing:';
            app.NCEIFormatToolLabel = uilabel(app.UIFigure);
            app.NCEIFormatToolLabel.Text = 'NCEI Format Tool';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'PA_Group_logo_lettering_notes_color.png';
            app.DetectorTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.DetectorTypeButtonGroup.Title = 'Detector Type';
            app.DolphinButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.DolphinButton.Text = 'Dolphin';
            app.DolphinButton.Value = true;
            app.MinkeButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.MinkeButton.Text = 'Minke';
            app.CodButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.CodButton.Text = 'Cod';
            app.VesselButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.VesselButton.Text = 'Vessel';
            app.GrouperButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.GrouperButton.Text = 'Grouper';
            app.PingerButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.PingerButton.Text = 'Pinger';
            app.LFDCSButton = uiradiobutton(app.DetectorTypeButtonGroup);
            app.LFDCSButton.Text = 'LFDCS';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.CurrentlySelectedTextArea = uitextarea(app.UIFigure);
            app.CurrentlySelectedTextArea.Editable = 'off';
            app.CurrentlySelectedTextArea.Value = {'Please select files...'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NCEI_format_tool_AID

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end