classdef Marina_ver1_01_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PAGroupDocksMenu             matlab.ui.container.Menu
        SanctSoundMenu               matlab.ui.container.Menu
        BoatsMenu                    matlab.ui.container.Menu
        AggregatedatadailyandhourlyMenu  matlab.ui.container.Menu
        FormatDataforNCEIMenu        matlab.ui.container.Menu
        CreateFrequencyHeatmapsMenu  matlab.ui.container.Menu
        MarinaLabel                  matlab.ui.control.Label
        MasterAcousticResearchGUINEFSCPAGroupLabel  matlab.ui.control.Label
        version10101Label            matlab.ui.control.Label
    end

    
    properties (Access = private)
        aggregateBoat % aggregate data daily and hourly app 
        NCEIformatBoat% format data for NCEI app
        FreqHeatmapBoat %create frequency heatmap dolphin wmd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: AggregatedatadailyandhourlyMenu
        function CallAggregateData(app, event)
            app. aggregateBoat = aggregate_data_daily_and_hourly;
        end

        % Menu selected function: FormatDataforNCEIMenu
        function FormatDataforNCEIMenuSelected(app, event)
            app.NCEIformatBoat = Format_data_for_NCEI;
        end

        % Menu selected function: CreateFrequencyHeatmapsMenu
        function CreateFrequencyHeatmapsMenuSelected(app, event)
            app.FreqHeatmapBoat = Create_Frequency_Heatmap;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PAGroupDocksMenu = uimenu(app.UIFigure);
            app.PAGroupDocksMenu.Text = 'PA Group Docks';
            app.SanctSoundMenu = uimenu(app.PAGroupDocksMenu);
            app.SanctSoundMenu.Text = 'SanctSound';
            app.BoatsMenu = uimenu(app.SanctSoundMenu);
            app.BoatsMenu.Text = 'Boats';
            app.AggregatedatadailyandhourlyMenu = uimenu(app.BoatsMenu);
            app.AggregatedatadailyandhourlyMenu.MenuSelectedFcn = createCallbackFcn(app, @CallAggregateData, true);
            app.AggregatedatadailyandhourlyMenu.Text = 'Aggregate data daily and hourly';
            app.FormatDataforNCEIMenu = uimenu(app.BoatsMenu);
            app.FormatDataforNCEIMenu.MenuSelectedFcn = createCallbackFcn(app, @FormatDataforNCEIMenuSelected, true);
            app.FormatDataforNCEIMenu.Text = 'Format Data for NCEI';
            app.CreateFrequencyHeatmapsMenu = uimenu(app.BoatsMenu);
            app.CreateFrequencyHeatmapsMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateFrequencyHeatmapsMenuSelected, true);
            app.CreateFrequencyHeatmapsMenu.Text = 'Create Frequency Heatmaps';
            app.MarinaLabel = uilabel(app.UIFigure);
            app.MarinaLabel.Text = 'Marina';
            app.MasterAcousticResearchGUINEFSCPAGroupLabel = uilabel(app.UIFigure);
            app.MasterAcousticResearchGUINEFSCPAGroupLabel.Text = 'Master Acoustic Research GUI NEFSC PA Group';
            app.version10101Label = uilabel(app.UIFigure);
            app.version10101Label.Text = 'version 1.01.01';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Marina_ver1_01_01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end